classdef ultraman1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Ctdown            matlab.ui.control.EditField
        StartButton       matlab.ui.control.Button
        EditField_2Label  matlab.ui.control.Label
        Score             matlab.ui.control.EditField
        RenewButton       matlab.ui.control.Button
        Label             matlab.ui.control.Label
        Round             matlab.ui.control.EditField
        DuangButton       matlab.ui.control.Button
        KeyButton         matlab.ui.control.Button
        Name              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global tot rds fns ct sc names dat
            dat = readtable('names.xlsx');
            a1 = dat.Properties.VariableNames;
            b1 = a1{1};
            eval(['namestot=dat.',b1]);
            tot = 54;
            names = namestot(1:tot);
            rds = 10;
            fns = randperm(tot,rds);
            ct = 0;
            sc = 0;
        end

        % Button pushed function: DuangButton
        function DuangButtonPushed(app, event)
            global sc
            sc = sc + 1;
            app.Score.Value = num2str(sc);
        end

        % Callback function
        function CtdownValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: RenewButton
        function ButtonPushed(app, event)
            global fns ct rds
            if ct==rds
                app.Round.Value = '结束了！';
                app.Round.FontSize = 12;
            else
                app.Name.Value = '你猜';
                ct = ct + 1;
                FileName = ['pic/',num2str(fns(ct)),'.png'];
                A = imread(FileName);
                image(app.UIAxes,A);
                app.UIAxes.DataAspectRatio = [1 1 1];
                
                % display # round
                app.Round.Value = num2str(ct);
                app.Round.FontSize = 20;
                % countdown
                a = 5:-1:0; % 5 seconds
                for i = 1:length(a)
                    app.Ctdown.Value = num2str(a(i));
                    pause(1) % produces after 1sec pause a new string in your gui
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Ctdown.Value = '5';
            cla(app.UIAxes);
            global ct sc fns tot rds
            fns = randperm(tot,rds);
            ct = 0;
            sc = 0;
            app.Score.Value = num2str(sc);
            app.Round.Value = num2str(ct);
        end

        % Button pushed function: KeyButton
        function KeyButtonPushed(app, event)
            global fns names ct
            app.Name.Value = names{fns(ct)};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Ctdown = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '开始游戏';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '得分';
            app.Score = uieditfield(app.UIFigure, 'text');
            app.RenewButton = uibutton(app.UIFigure, 'push');
            app.RenewButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RenewButton.Text = '来一次!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/';
            app.Round = uieditfield(app.UIFigure, 'text');
            app.DuangButton = uibutton(app.UIFigure, 'push');
            app.DuangButton.ButtonPushedFcn = createCallbackFcn(app, @DuangButtonPushed, true);
            app.DuangButton.Text = 'Duang!';
            app.KeyButton = uibutton(app.UIFigure, 'push');
            app.KeyButton.ButtonPushedFcn = createCallbackFcn(app, @KeyButtonPushed, true);
            app.KeyButton.Text = '查看答案';
            app.Name = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ultraman1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end